classdef competitionSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SimulationoptionsPanel         matlab.ui.container.Panel
        MakeequilibriumextrapolationfrompreequilibriumdataCheckBox  matlab.ui.control.CheckBox
        SimulatenoiseButton            matlab.ui.control.StateButton
        PlatereadingmodeButtonGroup    matlab.ui.container.ButtonGroup
        InverseddilutionButton         matlab.ui.control.ToggleButton
        TopdowndilutionButton          matlab.ui.control.ToggleButton
        InstantaneousButton            matlab.ui.control.ToggleButton
        SimulationparametersPanel      matlab.ui.container.Panel
        ExperimentaldesignparametersPanel  matlab.ui.container.Panel
        DilutionfactorEditField        matlab.ui.control.EditField
        DilutionfactorEditFieldLabel   matlab.ui.control.Label
        CompetitorlogcCKistartEditField  matlab.ui.control.EditField
        CompetitorlogcCKistartEditFieldLabel  matlab.ui.control.Label
        FittingtimeoptionsPanel        matlab.ui.container.Panel
        TimeendsEditField              matlab.ui.control.NumericEditField
        TimeendsEditFieldLabel         matlab.ui.control.Label
        TimestepsEditField             matlab.ui.control.NumericEditField
        TimestepsEditFieldLabel        matlab.ui.control.Label
        TimestartsEditField            matlab.ui.control.NumericEditField
        TimestartsEditFieldLabel       matlab.ui.control.Label
        SimulationtimeoptionsPanel     matlab.ui.container.Panel
        simulationtimeendsEditField    matlab.ui.control.NumericEditField
        simulationtimeendsEditFieldLabel  matlab.ui.control.Label
        simulationtimestepsEditField   matlab.ui.control.NumericEditField
        simulationtimestepsEditFieldLabel  matlab.ui.control.Label
        simulationtimestartsEditField  matlab.ui.control.NumericEditField
        simulationtimestartsEditFieldLabel  matlab.ui.control.Label
        kf1EditFieldLabel_13           matlab.ui.control.Label
        kf1EditFieldLabel_12           matlab.ui.control.Label
        kf1EditFieldLabel_11           matlab.ui.control.Label
        nMsLabel                       matlab.ui.control.Label
        kf1EditFieldLabel_10           matlab.ui.control.Label
        onEditFieldLabel_7             matlab.ui.control.Label
        kf1EditFieldLabel_8            matlab.ui.control.Label
        onEditFieldLabel_6             matlab.ui.control.Label
        kf1EditFieldLabel_7            matlab.ui.control.Label
        onEditFieldLabel_5             matlab.ui.control.Label
        kf1EditFieldLabel_6            matlab.ui.control.Label
        onEditFieldLabel_4             matlab.ui.control.Label
        kf1EditFieldLabel_5            matlab.ui.control.Label
        onEditFieldLabel_3             matlab.ui.control.Label
        kf1EditFieldLabel_4            matlab.ui.control.Label
        onEditFieldLabel_2             matlab.ui.control.Label
        kf1EditFieldLabel_3            matlab.ui.control.Label
        kf1EditFieldLabel_2            matlab.ui.control.Label
        onEditFieldLabel               matlab.ui.control.Label
        kf1EditFieldLabel_9            matlab.ui.control.Label
        V_R_stock                      matlab.ui.control.EditField
        L_total                        matlab.ui.control.EditField
        R_stock                        matlab.ui.control.EditField
        Kd2EditField                   matlab.ui.control.EditField
        kf2EditField                   matlab.ui.control.EditField
        Kd1EditField                   matlab.ui.control.EditField
        onEditField                    matlab.ui.control.EditField
        CopydataButton_4               matlab.ui.control.Button
        CopydataButton_3               matlab.ui.control.Button
        CopydataButton_2               matlab.ui.control.Button
        CopydataButton                 matlab.ui.control.Button
        SimulateButton                 matlab.ui.control.Button
        PlottimesEditField             matlab.ui.control.NumericEditField
        PlottimesEditFieldLabel        matlab.ui.control.Label
        TimeSlider                     matlab.ui.control.Slider
        TimeSliderLabel                matlab.ui.control.Label
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        proj % Description
        mod
        modeling_result_list 
        experiment_list
        dose_response_matrix
        logIC50s
        logKiFit1
        logKiFit2
        Rsquares
        RsquaresKi1
        RsquaresKi2
        meanTimes
        sigmoidConcentrations
        sigmoidFAValues
        completeModelCurves
        Ltotal
        BBVtotal
    end
    
    methods (Access = private)
        
        function results = calculateConditions(app)
            app.Ltotal = str2num(app.L_total.Value);
            app.BBVtotal = str2num(app.V_R_stock.Value);
            [app.modeling_result_list, app.experiment_list] = ModelSingleCompetitionExperiment(...
                app.BBVtotal, app.Ltotal, ...
                str2num(app.CompetitorlogcCKistartEditField.Value),...
                str2num(app.DilutionfactorEditField.Value),...
                str2num(app.onEditField.Value),...
                str2num(app.Kd1EditField.Value),...
                str2num(app.kf2EditField.Value),...
                str2num(app.Kd2EditField.Value),...
                str2num(app.R_stock.Value),...
                app.proj,...
                app.mod,...
                app.simulationtimestartsEditField.Value,...
                app.simulationtimestepsEditField.Value,...
                app.simulationtimeendsEditField.Value,...
                app.SimulatenoiseButton.Value);
            
                calculateDoseResponses(app)
        end
        
        function results = calculateDoseResponses(app)
            app.dose_response_matrix = modelSingleIC50Kinetics(...
                app.modeling_result_list,...
                app.experiment_list,...
                app.TimestartsEditField.Value,...
                app.TimestepsEditField.Value,...
                app.TimeendsEditField.Value,...
                [app.simulationtimestartsEditField.Value:...
                app.simulationtimestepsEditField.Value:...
                app.simulationtimeendsEditField.Value],...
                app.PlatereadingmodeButtonGroup.SelectedObject.Text);
            
            fo = fitoptions('Method','NonlinearLeastSquares',...
               'Lower',[-Inf,-Inf, -Inf],...
               'Upper',[Inf, Inf, Inf],...
               'StartPoint',[0.04 mean(app.dose_response_matrix{1}(:,2)), 0.14]);
            ft = fittype('Bottom + (Top-Bottom)/(1+10^((LogEC50-x)))','options',fo);
            meanTimes = zeros(numel(app.dose_response_matrix), 1);
            logIC50s = zeros(numel(app.dose_response_matrix), 1);
            logKiFit1 = zeros(numel(app.dose_response_matrix), 1);
            logKiFit2 = zeros(numel(app.dose_response_matrix), 1);
            Rsquares = zeros(numel(app.dose_response_matrix), 1);
            RsquaresKi1 = zeros(numel(app.dose_response_matrix), 1);
            RsquaresKi2 = zeros(numel(app.dose_response_matrix), 1);
            completeModelCurves = cell(numel(app.dose_response_matrix), 1);
            naiveKi = zeros(numel(app.dose_response_matrix), 1);
            for time_index = 1 : numel(app.dose_response_matrix)

                [curve2,gof2] = fit(app.dose_response_matrix{time_index}(:,2),app.dose_response_matrix{time_index}(:,3),ft);              
                meanTimes(time_index) = mean(app.dose_response_matrix{time_index}(:,1));
                logIC50s(time_index) = curve2.LogEC50;
                Rsquares(time_index) = gof2.rsquare;
                time_index
                
            end
            %return
            naiveKi
            hold(app.UIAxes_2,'off');
            plot(app.UIAxes_2, meanTimes, logIC50s, 'o');
            plot(app.UIAxes_4, meanTimes, Rsquares, 'o');
            app.meanTimes = meanTimes;
            app.logIC50s = logIC50s;
            app.Rsquares = Rsquares;
            
            fo = fitoptions('Method','NonlinearLeastSquares',...
               'Lower',[-5],...
               'Upper',[10],...
               'StartPoint',[mean(app.dose_response_matrix{1}(:,2))]);


            ft3 = fittype('competitionBindingExperiments(x, StockR, VolumeBV, WellVolume, StockNS, Kdns, Lt, Kd, logKi, Abound, Ans, Afree)', 'problem',{'StockR', 'VolumeBV', 'WellVolume', 'StockNS', 'Kdns', 'Lt', 'Kd', 'Ans', 'Afree', 'Abound'},'options',fo);
            
            for time_index = 1 : numel(app.dose_response_matrix)
                try
                    [curve2,gof2, O] = fit(10.^app.dose_response_matrix{time_index}(:,2),app.dose_response_matrix{time_index}(:,3),ft3, 'problem', {40, 10, 100, 10000, 1875000, 1, 1, 0.15, 0.04, 0.15});              
                    %[curve2,gof2] = fit(10.^app.dose_response_matrix{time_index}(:,2),app.dose_response_matrix{time_index}(:,3),ft3, 'problem', {40, 10, 100, 0, 18750000, 1, 1});              
                    completeModelCurves{time_index} = curve2;
                    meanTimes(time_index) = mean(app.dose_response_matrix{time_index}(:,1));
                    logKiFit1(time_index) = curve2.logKi;
                    RsquaresKi1(time_index) = gof2.rsquare;
                    time_index
                catch
                    'error'
                    logIC50s(time_index) = nan;
                    Rsquares(time_index) = nan;
                end
                
            end
            hold(app.UIAxes_2,'on');
            plot(app.UIAxes_2, meanTimes, logKiFit1, 'o');
            plot(app.UIAxes_4, meanTimes, RsquaresKi1, 'o');
            app.meanTimes = meanTimes;
            app.logIC50s = logIC50s;
            app.logKiFit1 = logKiFit1;
            app.RsquaresKi1 = RsquaresKi1;
            app.completeModelCurves = completeModelCurves;


            funParam = @(X, Ytarget, StockR, VolumeBV, WellVolume, StockNS, Kdns, Lt, Kd, logKi, Abound, Ans, Afree, thetaFunction) competitionBindingExperimentsOptimization(X, Ytarget, StockR, VolumeBV, WellVolume, StockNS, Kdns, Lt, Kd, logKi, Abound, Ans, Afree, thetaFunction);
            options = optimset('MaxFunEvals', 2000, 'MaxIter', 1000, 'TolFun', 0.0000001, 'TolX', 0.000001);

            for time_index = 1 : numel(app.dose_response_matrix)
                try
                    %logKi = fminsearch(@(logKi) funParam({10.^app.dose_response_matrix{time_index}(:,2)},{app.dose_response_matrix{time_index}(:,3)},40,10,100,1000,18750000,1,1,logKi,0.11,0.27,0.08), [mean(app.dose_response_matrix{1}(:,2))],...
                    %    options);
                    symTheta = str2sym('acos((-2.*A.^3+9.*A.*B-27.*C)./(2*sqrt((A.^2-3.*B).^3)))');
                    res = fminsearch(@(params) funParam({10.^app.dose_response_matrix{time_index}(:,2)},{app.dose_response_matrix{time_index}(:,3)},str2num(app.R_stock.Value),app.BBVtotal,100,10000,1875000,app.Ltotal,str2num(app.Kd1EditField.Value),params(1), params(2), params(3), params(4), symTheta), [mean(app.dose_response_matrix{1}(:,2)), 0.15, 0.15, 0.04],...
                        options);
                    logKi = res(1);
                    logKiFit2(time_index) = logKi;
                    time_index
                catch ME
                    ''
                end
            end
            
            hold(app.UIAxes_2,'on');
            plot(app.UIAxes_2, meanTimes, logKiFit2, 'o');
            plot(app.UIAxes_4, meanTimes, Rsquares, 'o');
            app.meanTimes = meanTimes;
            app.logKiFit2 = logKiFit2;
            %app.RsquaresKi2 = RsquaresKi2;
            app.completeModelCurves = completeModelCurves;
            
            
            fo = fitoptions('Method','NonlinearLeastSquares',...
            'Lower',[-20,-10, -20],...
            'Upper',[20, 0, 20],...
            'StartPoint',[-0.5 -0.0003, 1.5]);


            ft2 = fittype('c+a*exp(b*x)','options',fo);
            
            if(app.MakeequilibriumextrapolationfrompreequilibriumdataCheckBox.Value)
            
                heatMap = nan(numel(app.dose_response_matrix), numel(app.dose_response_matrix));
                for time_start_index = 1 : numel(app.dose_response_matrix) - 2
                    for time_end_index = time_start_index + 3 : numel(app.dose_response_matrix)
                        try
                            [curve3,gof3] = fit(meanTimes(time_start_index : time_end_index),logIC50s(time_start_index : time_end_index),ft2);              
                            heatMap(time_start_index, time_end_index) = curve3.c;
                        catch
                            heatMap(time_start_index, time_end_index) = nan;
    
                        end
                        time_start_index
                    end
                    for time_end_index = 1 : time_start_index + 2
                        heatMap(time_start_index, time_end_index) = nan;
                    end
                end
            end
            figure
            heatmap(heatMap)
        end
        
        function plotIC50(app)
            plot
        end
        
        function results = func4(app, tableToCopy)
            resultString = [];
            for row = 1 : size(tableToCopy, 1)
                for col = 1 : size(tableToCopy, 2)
                    if isempty(resultString)
                        resultString = num2str(tableToCopy(row, col));
                    elseif isequal(col, 1)
                        resultString = [resultString, num2str(tableToCopy(row, col))];
                    else
                        resultString = [resultString, char(9), num2str(tableToCopy(row, col))];
                    end
                end
                resultString = [resultString, char(10)];
            end
            clipboard('copy', resultString);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function projectAndModelInitialization(app)
            mainPath = 'C:\Users\laasfeld\OneDrive - Tartu Ülikool\Muskariini raamatupeatükk\Simualtions';
            app.proj = IQMprojectSB(fullfile(mainPath, 'project_shell.iqmp'));
            mod = load(fullfile(mainPath, 'modelstruct.mat')); % loads variable "mod" which is a model structure for 
            app.mod = mod.mod;
        end

        % Value changed function: onEditField
        function onEditFieldValueChanged(app, event)
            %calculateConditions(app)
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            value = app.TimeSlider.Value/100;
            timeVector = [app.TimestartsEditField.Value : app.TimestepsEditField.Value : app.TimeendsEditField.Value];
            nrElems = numel(timeVector);
            elemIndex = round(nrElems*value);
            hold(app.UIAxes, 'off');
            plot(app.UIAxes, app.dose_response_matrix{elemIndex}(:,2), app.dose_response_matrix{elemIndex}(:,3), 'o');
            hold(app.UIAxes, 'on');
            %[curve2,gof2] = fit(10.^app.dose_response_matrix{time_index}(:,2),app.dose_response_matrix{time_index}(:,3),ft3, 'problem', {40, 10, 100, 0, 18750000, 1, 1});              
            %curve2 = app.completeModelCurves{elemIndex};
            %plot(app.UIAxes, app.dose_response_matrix{elemIndex}(:,2), curve2(10.^app.dose_response_matrix{elemIndex}(:,2)))
            app.sigmoidConcentrations = app.dose_response_matrix{elemIndex}(:,2);
            app.sigmoidFAValues = app.dose_response_matrix{elemIndex}(:,3);
        end

        % Button pushed function: CopydataButton
        function CopydataButtonPushed(app, event)
            app.func4([app.sigmoidConcentrations, app.sigmoidFAValues])
            %clipboard('copy',[app.sigmoidConcentrations, app.sigmoidFAValues]);        
        end

        % Button pushed function: CopydataButton_2
        function CopydataButton_2Pushed(app, event)
            app.func4([app.meanTimes, app.logIC50s, app.logKiFit1, app.logKiFit2])

        end

        % Button pushed function: CopydataButton_4
        function CopydataButton_4Pushed(app, event)
            app.func4([app.meanTimes, app.Rsquares])

        end

        % Value changed function: SimulatenoiseButton
        function SimulatenoiseButtonValueChanged(app, event)
            value = app.SimulatenoiseButton.Value;
            
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            calculateConditions(app);
        end

        % Callback function
        function ReadsimulationButtonGroupSizeChanged(app, event)
            position = app.ReadsimulationButtonGroup.Position;
            
        end

        % Callback function
        function ReadsimulationButtonGroupSelectionChanged(app, event)
            selectedButton = app.ReadsimulationButtonGroup.SelectedObject;
            
        end

        % Selection changed function: PlatereadingmodeButtonGroup
        function PlatereadingmodeButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlatereadingmodeButtonGroup.SelectedObject;
            
        end

        % Value changed function: 
        % MakeequilibriumextrapolationfrompreequilibriumdataCheckBox
        function MakeequilibriumextrapolationfrompreequilibriumdataCheckBoxValueChanged(app, event)
            value = app.MakeequilibriumextrapolationfrompreequilibriumdataCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Dose response plot')
            xlabel(app.UIAxes, 'log_{10}(c(Competitor)/nM)')
            ylabel(app.UIAxes, 'FA')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'pIC_{50} and pK_i in time')
            xlabel(app.UIAxes_2, 'time, min')
            ylabel(app.UIAxes_2, 'pIC_{50} or pK_i')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Pre-equilibrium affinity extrapolation')
            xlabel(app.UIAxes_3, 'extrapolation start time, min')
            ylabel(app.UIAxes_3, 'extrapolation end time, min')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'R^2 in time')
            xlabel(app.UIAxes_4, 'time, min')
            ylabel(app.UIAxes_4, 'R^2')
            zlabel(app.UIAxes_4, 'Z')
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.PlottimesEditFieldLabel = uilabel(app.UIFigure);
            app.PlottimesEditFieldLabel.Text = 'Plot time, s';
            app.PlottimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.CopydataButton = uibutton(app.UIFigure, 'push');
            app.CopydataButton.ButtonPushedFcn = createCallbackFcn(app, @CopydataButtonPushed, true);
            app.CopydataButton.Text = 'Copy data';
            app.CopydataButton_2 = uibutton(app.UIFigure, 'push');
            app.CopydataButton_2.ButtonPushedFcn = createCallbackFcn(app, @CopydataButton_2Pushed, true);
            app.CopydataButton_2.Text = 'Copy data';
            app.CopydataButton_3 = uibutton(app.UIFigure, 'push');
            app.CopydataButton_3.Text = 'Copy data';
            app.CopydataButton_4 = uibutton(app.UIFigure, 'push');
            app.CopydataButton_4.ButtonPushedFcn = createCallbackFcn(app, @CopydataButton_4Pushed, true);
            app.CopydataButton_4.Text = 'Copy data';
            app.SimulationparametersPanel = uipanel(app.UIFigure);
            app.SimulationparametersPanel.Title = 'Simulation parameters';
            app.onEditField = uieditfield(app.SimulationparametersPanel, 'text');
            app.onEditField.ValueChangedFcn = createCallbackFcn(app, @onEditFieldValueChanged, true);
            app.onEditField.Value = '0.0007';
            app.Kd1EditField = uieditfield(app.SimulationparametersPanel, 'text');
            app.Kd1EditField.Value = '1';
            app.kf2EditField = uieditfield(app.SimulationparametersPanel, 'text');
            app.kf2EditField.Value = '0.004';
            app.Kd2EditField = uieditfield(app.SimulationparametersPanel, 'text');
            app.Kd2EditField.Value = '1';
            app.R_stock = uieditfield(app.SimulationparametersPanel, 'text');
            app.R_stock.Value = '1';
            app.L_total = uieditfield(app.SimulationparametersPanel, 'text');
            app.L_total.Value = '1';
            app.V_R_stock = uieditfield(app.SimulationparametersPanel, 'text');
            app.V_R_stock.Value = '1';
            app.kf1EditFieldLabel_9 = uilabel(app.SimulationparametersPanel);
            app.kf1EditFieldLabel_9.Text = ',%';
            app.onEditFieldLabel = uilabel(app.SimulationparametersPanel);
            app.onEditFieldLabel.Text = 'on';
            app.kf1EditFieldLabel_2 = uilabel(app.SimulationparametersPanel);
            app.kf1EditFieldLabel_2.Text = {'k   ,1/(nM*s)           '; ''};
            app.kf1EditFieldLabel_3 = uilabel(app.SimulationparametersPanel);
            app.kf1EditFieldLabel_3.Text = 'K';
            app.onEditFieldLabel_2 = uilabel(app.SimulationparametersPanel);
            app.onEditFieldLabel_2.Text = 'd';
            app.kf1EditFieldLabel_4 = uilabel(app.SimulationparametersPanel);
            app.kf1EditFieldLabel_4.Text = {'k'; ''};
            app.onEditFieldLabel_3 = uilabel(app.SimulationparametersPanel);
            app.onEditFieldLabel_3.Text = 'on, competitor';
            app.kf1EditFieldLabel_5 = uilabel(app.SimulationparametersPanel);
            app.kf1EditFieldLabel_5.Text = 'K';
            app.onEditFieldLabel_4 = uilabel(app.SimulationparametersPanel);
            app.onEditFieldLabel_4.Text = 'i';
            app.kf1EditFieldLabel_6 = uilabel(app.SimulationparametersPanel);
            app.kf1EditFieldLabel_6.Text = 'R';
            app.onEditFieldLabel_5 = uilabel(app.SimulationparametersPanel);
            app.onEditFieldLabel_5.Text = 'stock';
            app.kf1EditFieldLabel_7 = uilabel(app.SimulationparametersPanel);
            app.kf1EditFieldLabel_7.Text = 'L';
            app.onEditFieldLabel_6 = uilabel(app.SimulationparametersPanel);
            app.onEditFieldLabel_6.Text = 'total';
            app.kf1EditFieldLabel_8 = uilabel(app.SimulationparametersPanel);
            app.kf1EditFieldLabel_8.Text = 'V';
            app.onEditFieldLabel_7 = uilabel(app.SimulationparametersPanel);
            app.onEditFieldLabel_7.Text = 'receptor_stock';
            app.kf1EditFieldLabel_10 = uilabel(app.SimulationparametersPanel);
            app.kf1EditFieldLabel_10.Text = ',nM';
            app.nMsLabel = uilabel(app.SimulationparametersPanel);
            app.nMsLabel.Text = ',1/(nM*s)';
            app.kf1EditFieldLabel_11 = uilabel(app.SimulationparametersPanel);
            app.kf1EditFieldLabel_11.Text = ',nM';
            app.kf1EditFieldLabel_12 = uilabel(app.SimulationparametersPanel);
            app.kf1EditFieldLabel_12.Text = ',nM';
            app.kf1EditFieldLabel_13 = uilabel(app.SimulationparametersPanel);
            app.kf1EditFieldLabel_13.Text = ',nM';
            app.SimulationtimeoptionsPanel = uipanel(app.SimulationparametersPanel);
            app.SimulationtimeoptionsPanel.Title = 'Simulation time options';
            app.simulationtimestartsEditFieldLabel = uilabel(app.SimulationtimeoptionsPanel);
            app.simulationtimestartsEditFieldLabel.Text = 'simulation time start, s';
            app.simulationtimestartsEditField = uieditfield(app.SimulationtimeoptionsPanel, 'numeric');
            app.simulationtimestepsEditFieldLabel = uilabel(app.SimulationtimeoptionsPanel);
            app.simulationtimestepsEditFieldLabel.Text = 'simulation time step, s';
            app.simulationtimestepsEditField = uieditfield(app.SimulationtimeoptionsPanel, 'numeric');
            app.simulationtimestepsEditField.Value = 5;
            app.simulationtimeendsEditFieldLabel = uilabel(app.SimulationtimeoptionsPanel);
            app.simulationtimeendsEditFieldLabel.Text = 'simulation time end, s';
            app.simulationtimeendsEditField = uieditfield(app.SimulationtimeoptionsPanel, 'numeric');
            app.simulationtimeendsEditField.Value = 10000;
            app.FittingtimeoptionsPanel = uipanel(app.SimulationparametersPanel);
            app.FittingtimeoptionsPanel.Title = 'Fitting time options';
            app.TimestartsEditFieldLabel = uilabel(app.FittingtimeoptionsPanel);
            app.TimestartsEditFieldLabel.Text = 'Time start, s';
            app.TimestartsEditField = uieditfield(app.FittingtimeoptionsPanel, 'numeric');
            app.TimestartsEditField.Value = 60;
            app.TimestepsEditFieldLabel = uilabel(app.FittingtimeoptionsPanel);
            app.TimestepsEditFieldLabel.Text = 'Time step, s';
            app.TimestepsEditField = uieditfield(app.FittingtimeoptionsPanel, 'numeric');
            app.TimestepsEditField.Value = 144;
            app.TimeendsEditFieldLabel = uilabel(app.FittingtimeoptionsPanel);
            app.TimeendsEditFieldLabel.Text = 'Time end, s';
            app.TimeendsEditField = uieditfield(app.FittingtimeoptionsPanel, 'numeric');
            app.TimeendsEditField.Value = 10000;
            app.ExperimentaldesignparametersPanel = uipanel(app.SimulationparametersPanel);
            app.ExperimentaldesignparametersPanel.Title = 'Experimental design parameters';
            app.CompetitorlogcCKistartEditFieldLabel = uilabel(app.ExperimentaldesignparametersPanel);
            app.CompetitorlogcCKistartEditFieldLabel.Text = 'Competitor log(c(C)/Ki) start';
            app.CompetitorlogcCKistartEditField = uieditfield(app.ExperimentaldesignparametersPanel, 'text');
            app.CompetitorlogcCKistartEditField.Value = '-3';
            app.DilutionfactorEditFieldLabel = uilabel(app.ExperimentaldesignparametersPanel);
            app.DilutionfactorEditFieldLabel.Text = 'Dilution factor';
            app.DilutionfactorEditField = uieditfield(app.ExperimentaldesignparametersPanel, 'text');
            app.DilutionfactorEditField.Value = '8';
            app.SimulationoptionsPanel = uipanel(app.UIFigure);
            app.SimulationoptionsPanel.Title = 'Simulation options';
            app.PlatereadingmodeButtonGroup = uibuttongroup(app.SimulationoptionsPanel);
            app.PlatereadingmodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlatereadingmodeButtonGroupSelectionChanged, true);
            app.PlatereadingmodeButtonGroup.Title = 'Plate reading mode';
            app.InstantaneousButton = uitogglebutton(app.PlatereadingmodeButtonGroup);
            app.InstantaneousButton.Text = 'Instantaneous';
            app.InstantaneousButton.Value = true;
            app.TopdowndilutionButton = uitogglebutton(app.PlatereadingmodeButtonGroup);
            app.TopdowndilutionButton.Text = 'Top down dilution';
            app.InverseddilutionButton = uitogglebutton(app.PlatereadingmodeButtonGroup);
            app.InverseddilutionButton.Text = 'Inversed dilution';
            app.SimulatenoiseButton = uibutton(app.SimulationoptionsPanel, 'state');
            app.SimulatenoiseButton.ValueChangedFcn = createCallbackFcn(app, @SimulatenoiseButtonValueChanged, true);
            app.SimulatenoiseButton.Text = 'Simulate noise';
            app.MakeequilibriumextrapolationfrompreequilibriumdataCheckBox = uicheckbox(app.SimulationoptionsPanel);
            app.MakeequilibriumextrapolationfrompreequilibriumdataCheckBox.ValueChangedFcn = createCallbackFcn(app, @MakeequilibriumextrapolationfrompreequilibriumdataCheckBoxValueChanged, true);
            app.MakeequilibriumextrapolationfrompreequilibriumdataCheckBox.Text = 'Make equilibrium extrapolation from pre-equilibrium data (can be slow)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = competitionSimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @projectAndModelInitialization)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end